classdef ENCMP100_Contest_Program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ENCMP100ImageProcessingAppUIFigure  matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        ImageProcessingOptionsPanel  matlab.ui.container.Panel
        GreyscaleButton              matlab.ui.control.Button
        BlackWhiteButton             matlab.ui.control.Button
        SharpeningButton             matlab.ui.control.Button
        FlipLRButton                 matlab.ui.control.Button
        RotateDegreesEditFieldLabel  matlab.ui.control.Label
        RotateDegreesEditField       matlab.ui.control.NumericEditField
        CropImageButton              matlab.ui.control.Button
        IntensityofGreyscaleBlurEditFieldLabel  matlab.ui.control.Label
        IntensityofGreyscaleBlurEditField  matlab.ui.control.NumericEditField
        LoadingSavingPanel           matlab.ui.container.Panel
        Select_Image                 matlab.ui.control.Button
        SaveImageButton              matlab.ui.control.Button
        ENCMP100IMAGEPROCESSINGAPPLabel  matlab.ui.control.Label
    end

            properties (Access = private)
            %Create variables to be used throughout the app in other functions
            %ImageFile is the actual image itself
            %saved is to keep track of if the image has been saved/transformed
            %saved is used to determine whether to display warning message on close
            %When accessing variable, use app.variable 
            ImageFile 
            saved
            end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %Preallocation of saved variable to be used later when closing the app
        %Used to detect if a warning should be displayed
                app.saved = 0;    
        end

        % Button pushed function: Select_Image
        function Select_ImageButtonPushed(app, event)
        %After Button is pressed menu for user to select image is created
        %Acceptable image files are .jpeg, .jpg, and .png    
            [filename,filepath] = uigetfile({'*'});
        %Create error message if user does not select a file
        %Empty filename is equal to 0 and error will appear
            if filename == 0 
                errordlg('Please choose an image file.');
            else
        %Assigns extension to be compared
            [~, ~, ext] = fileparts(filename);
        %Clarifies the allowed formats for this app and stores the characters into a vector. 
            allowedformats = ['*.jpg*','*.jpeg','*.png'];
        %Compares the extension element with the allowedformats vector. Returns a logical vector of 1's and zeros. 
        %If the extension does not match any of the elements in allowed formats, the logical vector returned is [ 0 0 0].
        %So if the logical vector returned is equal to [0 0 0], then the error message of invalid file type is triggered. 
                if contains(allowedformats,ext) == [0 0 0]
                errordlg('Invalid file type. Please choose a valid file to open. You can only select .jpg, .jpeg, and .png files.');
                else
        %Read the image
                fullname = [filepath filename];
        %Also create variable to be used throughout app
                app.ImageFile = imread(fullname);
        %Display Image onto App Designer using Axes. 
        %Axes and axes labels are not shown to make it cleaner
                imshow(app.ImageFile,'parent',app.UIAxes);
                end
            end
        end

        % Value changed function: RotateDegreesEditField
        function RotateDegreesEditFieldValueChanged(app, event)
        %If rotating a blurred image, output is a white image
        %Create error so user cannot rotate a blurred image
        %Suggest workaround in error message
        %Numerical Value to be inputted 
         if app.saved == 2
                errordlg('Cannot rotate a blurred image. First rotate then blur.', 'Rotation Error');
         else 
            angle = app.RotateDegreesEditField.Value;
        %Set limits to angle. Use if case 
        %Display error if out of bounds
            if angle > 1000 || angle < -1000
                errordlg('Please enter a valid angle rotate. This feature can only rotate up to 1000 and -1000 degrees', 'Rotation Error');
            else
        %Rotate Image 
            A = imrotate(app.ImageFile, angle);
        %Display Image
            imshow(A,'parent',app.UIAxes);
        %Revert back to app.ImageFile so that rotation is saved
            app.ImageFile = A;
        %Set app.saved to 1 to show user has done a transformation
            app.saved = 1;
                end
            end
        end

        % Button pushed function: GreyscaleButton
        function GreyscaleButtonPushed(app, event)
        %Set app.saved to 1 to show user has done a transformation
            
        %Take app.ImageFile and convert to GreyScale
            if size(app.ImageFile,3) == 1
                errordlg('This Image is already in grey scale or cannot be converted.', 'Grey Scale Error');
            else
                B = rgb2gray(app.ImageFile);
        %Display Image
                imshow(B,'parent',app.UIAxes);
        %Revert back to app.ImageFile so that greyscale is saved
                app.ImageFile = B;
                app.saved = 1;
            end
        end

        % Button pushed function: BlackWhiteButton
        function BlackWhiteButtonPushed(app, event)
        %If transforming a blurred image, output is a white image
        %Create error so user cannot rotate a blurred image
         if app.saved == 2;
               errordlg('Cannot apply further transformations to blurred images.', 'Error')
         else
        %Take app.ImageFile and convert to Binary Image
            isBinaryImage = islogical(app.ImageFile);
        %Use if case to detect if the image is already a binary image
        %If it is a binary image display warning
            if isBinaryImage == 1
                errordlg('This image is already in binary form.', 'Binary Error');
            elseif size(app.ImageFile,3)~=1
                C = imbinarize(rgb2gray(app.ImageFile));
        %Display Image
            imshow(C,'parent',app.UIAxes);
        %Revert back to app.ImageFile so that Black and White is saved
            app.ImageFile = C; 
            else 
                size(app.ImageFile, 3) == 1;
                X = imbinarize(app.ImageFile);
        %Display Image 
            imshow(X,'parent',app.UIAxes);
        %Revert back to app.ImageFile so that Black and White is saved
            app.ImageFile = X;
        %Set app.saved to 1 to show user has done a transformation
            app.saved = 1;
                end
            end
        end

        % Button pushed function: CropImageButton
        function CropImageButtonPushed(app, event)
        %If transforming a blurred image, output is a white image
        %Create error so user cannot rotate a blurred image
        if app.saved == 2;
            errordlg('Cannot apply further transformations to blurred images.', 'Error')
         else
        %Take app.ImageFile and convert 
            D = imcrop(app.ImageFile);
        %Close figure immediately after user crops image
        %Use if case to check if the crop tool was actually used
        %When image is not cropped the size of image is 0
        %Error appears 
            if size(D) == 0
                errordlg('Cropping error. Image is empty');
            else
                close Figure 1
        %Display Image
            imshow(D,'parent',app.UIAxes);
        %Revert back to app.ImageFile so that crop is saved
            app.ImageFile = D; 
        %Set app.saved to 1 to show user has done a transformation
            app.saved = 1;
            end
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
        %Prompt user to save image after button press
            [filename, filepath]= uiputfile({'*.jpg';'*.jpeg';'*.png'}, 'Save As');
        %Save image as a jpg file
            save = [filepath filename]; 
        %Display warning is user does not save the image    
            if filename == 0
                warndlg('Image was not saved');
            else
            imwrite(app.ImageFile,save,'jpg');
        %Set app.saved to 3 so that warning message will not appear upon closing app
            app.saved = 3;
            end
        end

        % Value changed function: IntensityofGreyscaleBlurEditField
        function IntensityofGreyscaleBlurEditFieldValueChanged(app, event)
        %Set app.saved to 2 to show user has done a transformation
        %app.saved is set to 2 so that the app can tell that image has been blurred. 
        %This differs from other app.saved values
        %Used in rotation function
            app.saved = 2;
        %initialise binary image condition for error message check.
            isBinaryImage = islogical(app.ImageFile);
        %Apply Blur
            int_blur = app.IntensityofGreyscaleBlurEditField.Value;
        %Detect if image is greyscale or binary image
        %Image must be greyscale to run blur.
            if size(app.ImageFile,3)~=1 || isBinaryImage == 1   
                errordlg('Make image greyscale before using Blur. Also, cannot apply blur to binary images.', 'Blur Error');
        %If the entered intensity of blur is more than 10, less than 1, even numbers
        %The following error message is triggered.
            elseif int_blur >=10 || int_blur < 1  || mod(int_blur,2) == 0 
                errordlg('Intensity can range from 1-10, odd numbers only,', 'Blur Error');
            else
        %USe Switch case to determine input of blur
                switch int_blur
                    case 1
                        E = app.ImageFile;
        %Case 1 creates a matrix of 1x1 dimensions. 
                        mat_1by1 = [1];
        %Creates a filter with the dimensions of the matrix in this specific case.
                        one = filter2(mat_1by1, E);
        %Show the blurred image after the blur filter has been applied. 
                        imshow(uint8(one),'parent', app.UIAxes);
                        app.ImageFile = one;
                    case 3
                        F = app.ImageFile;
        %Case 3 creates a matrix of 3x3 dimensions. 
                        mat_3by3 = [1/9, 1/9, 1/9;...
                            1/9, 1/9, 1/9;...
                            1/9, 1/9, 1/9];
        %Creates a filter with the dimensions of the matrix in this specific case.
                        three = filter2(mat_3by3, F);
        %Show the blurred image after the blur filter has been applied. 
                        imshow(uint8(three),'parent', app.UIAxes);
                        app.ImageFile = three;
                    case 5
                        G = app.ImageFile;
        %Case 5 creates a matrix of 5x5 dimensions. 
                        mat_5by5 = [1/25 1/25 1/25 1/25 1/25; 
                            1/25 1/25 1/25 1/25 1/25;...
                            1/25 1/25 1/25 1/25 1/25;...
                            1/25 1/25 1/25 1/25 1/25;...
                            1/25 1/25 1/25 1/25 1/25];
        %Creates a filter with the dimensions of the matrix in this specific case.
                        five = filter2(mat_5by5, G);
        %Show the blurred image after the blur filter has been applied. 
                        imshow(uint8(five),'parent', app.UIAxes);
                        app.ImageFile = five;
                    case 7
                        H = app.ImageFile;
        %Case 7 creates a matrix of 7x7 dimensions. 
                        mat_7by7 = [1/49 1/49 1/49 1/49 1/49 1/49 1/49;...
                            1/49 1/49 1/49 1/49 1/49 1/49 1/49;...
                            1/49 1/49 1/49 1/49 1/49 1/49 1/49;...
                            1/49 1/49 1/49 1/49 1/49 1/49 1/49;...
                            1/49 1/49 1/49 1/49 1/49 1/49 1/49;...
                            1/49 1/49 1/49 1/49 1/49 1/49 1/49;...
                            1/49 1/49 1/49 1/49 1/49 1/49 1/49];
        %Creates a filter with the dimensions of the matrix in this specific case.
                        seven = filter2(mat_7by7, H);
        %Show the blurred image after the blur filter has been applied. 
                        imshow(uint8(seven),'parent', app.UIAxes);
                        app.ImageFile = seven;
                    case 9
                        I = app.ImageFile;
        %Case 9 creates a matrix of 9x9 dimensions. 
                        mat_9by9 = [1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81;...
                            1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81;...
                            1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81;...
                            1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81;...
                            1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81;...
                            1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81;...
                            1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81;...
                            1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81;...
                            1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81 1/81];
        %Creates a filter with the dimensions of the matrix in this specific case.
                        nine = filter2(mat_9by9, I);
        %Show the blurred image after the blur filter has been applied. 
                        imshow(uint8(nine),'parent', app.UIAxes);
                        app.ImageFile = nine;
                end
            end
        end

        % Close request function: ENCMP100ImageProcessingAppUIFigure
        function ENCMP100ImageProcessingAppUIFigureCloseRequest(app, event)
        %If user has not saved transformed image create a warning screen
        %Only applies if user has applied a transformation
        %At least one transformation to trigger warning
        %User is prompted to decde if they want to leave  
        %Simple switch case to decide whether to leave
            if app.saved == 1 || app.saved == 2
                choice = questdlg('You have not saved. Are you want to quit?', 'Quit',...
                'Yes','No','Yes');
                switch choice
                    case 'Yes'
                        delete(app)
                    case 'No'
                        return
                end
        %If user has not applied transformation or has not loaded an image, delete immediately
        %Also delete immediately if user has saved (app.saved = 3) so won't trigger
            else 
                delete(app)
            end
        end

        % Button pushed function: SharpeningButton
        function SharpeningButtonPushed(app, event)
        %If transforming a blurred image, output is a white image
        %Create error so user cannot rotate a blurred image
         if app.saved == 2;
               errordlg('Cannot apply further transformations to blurred images.', 'Error');
            else
            
        %Check first to see if the image is binary. if the image is binary, then the error message is triggered
        %Because you cannot further sharpen a binary image. 
            isBinaryImage = islogical(app.ImageFile);
            if isBinaryImage == 1
                errordlg('Binary images are already at maximum contrast. Please pick another image','Sharpening Error');
        %Else, if the image type is NOT grey scale, then adjust the contrast with a 2x3 matrix. RGB images store more information than greyscale images, so therefore a larger matrix is required. 
            elseif size(app.ImageFile,3)~=1
                J = imadjust(app.ImageFile,[.2,.3,0;.6,.7,1],[]);
                imshow(J,'parent',app.UIAxes);
                app.ImageFile = J;
            else 
        %Else the image must be greyscale, so just adjust the contrast normally with a 1x2 matrix. 
            K = imadjust(app.ImageFile,[.1,.4],[]);
        %Display image and revert    
            imshow(K,'parent',app.UIAxes);
            app.ImageFile = K;
        %Set app.saved to 1 to show user has done a transformation
            app.saved = 1;
            end
            end
        end

        % Button pushed function: FlipLRButton
        function FlipLRButtonPushed(app, event)
        %If transforming a blurred image, output is a white image
        %Create error so user cannot rotate a blurred image
            if app.saved == 2;
               errordlg('Cannot apply further transformations to blurred images.', 'Error')
            else                
        %Flips the image horizontally. 
            L = fliplr(app.ImageFile);
        %Display image and revert
            imshow(L, 'parent', app.UIAxes);
            app.ImageFile = L;
        %Set app.saved to 1 to show user has done a transformation
            app.saved = 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ENCMP100ImageProcessingAppUIFigure = uifigure;
            app.ENCMP100ImageProcessingAppUIFigure.Name = 'ENCMP 100 Image Processing App';
            app.ENCMP100ImageProcessingAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @ENCMP100ImageProcessingAppUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ENCMP100ImageProcessingAppUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = 0;
            app.UIAxes.XTickLabel = {'0'};
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = {'0'};
            app.ImageProcessingOptionsPanel = uipanel(app.ENCMP100ImageProcessingAppUIFigure);
            app.ImageProcessingOptionsPanel.Title = 'Image Processing Options';
            app.GreyscaleButton = uibutton(app.ImageProcessingOptionsPanel, 'push');
            app.GreyscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyscaleButtonPushed, true);
            app.GreyscaleButton.Text = 'Greyscale';
            app.BlackWhiteButton = uibutton(app.ImageProcessingOptionsPanel, 'push');
            app.BlackWhiteButton.ButtonPushedFcn = createCallbackFcn(app, @BlackWhiteButtonPushed, true);
            app.BlackWhiteButton.Text = 'Black/White';
            app.SharpeningButton = uibutton(app.ImageProcessingOptionsPanel, 'push');
            app.SharpeningButton.ButtonPushedFcn = createCallbackFcn(app, @SharpeningButtonPushed, true);
            app.SharpeningButton.Text = 'Sharpening';
            app.FlipLRButton = uibutton(app.ImageProcessingOptionsPanel, 'push');
            app.FlipLRButton.ButtonPushedFcn = createCallbackFcn(app, @FlipLRButtonPushed, true);
            app.FlipLRButton.Text = 'Flip L/R';
            app.RotateDegreesEditFieldLabel = uilabel(app.ImageProcessingOptionsPanel);
            app.RotateDegreesEditFieldLabel.Text = 'Rotate (Degrees)';
            app.RotateDegreesEditField = uieditfield(app.ImageProcessingOptionsPanel, 'numeric');
            app.RotateDegreesEditField.ValueChangedFcn = createCallbackFcn(app, @RotateDegreesEditFieldValueChanged, true);
            app.CropImageButton = uibutton(app.ImageProcessingOptionsPanel, 'push');
            app.CropImageButton.ButtonPushedFcn = createCallbackFcn(app, @CropImageButtonPushed, true);
            app.CropImageButton.Text = {'Crop Image'; ''};
            app.IntensityofGreyscaleBlurEditFieldLabel = uilabel(app.ImageProcessingOptionsPanel);
            app.IntensityofGreyscaleBlurEditFieldLabel.Text = {'Intensity of'; 'Greyscale Blur '};
            app.IntensityofGreyscaleBlurEditField = uieditfield(app.ImageProcessingOptionsPanel, 'numeric');
            app.IntensityofGreyscaleBlurEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityofGreyscaleBlurEditFieldValueChanged, true);
            app.LoadingSavingPanel = uipanel(app.ENCMP100ImageProcessingAppUIFigure);
            app.LoadingSavingPanel.Title = 'Loading/Saving';
            app.Select_Image = uibutton(app.LoadingSavingPanel, 'push');
            app.Select_Image.ButtonPushedFcn = createCallbackFcn(app, @Select_ImageButtonPushed, true);
            app.Select_Image.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.LoadingSavingPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ENCMP100IMAGEPROCESSINGAPPLabel = uilabel(app.ENCMP100ImageProcessingAppUIFigure);
            app.ENCMP100IMAGEPROCESSINGAPPLabel.Text = 'ENCMP 100 IMAGE PROCESSING APP';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ENCMP100_Contest_Program

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ENCMP100ImageProcessingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ENCMP100ImageProcessingAppUIFigure)
        end
    end
end